classdef MotionLabHMI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActuatorsPositionPanel          matlab.ui.container.Panel
        PositionGauge_7                 matlab.ui.control.LinearGauge
        PositionGauge_6                 matlab.ui.control.LinearGauge
        PositionGauge_5                 matlab.ui.control.LinearGauge
        PositionGauge_4                 matlab.ui.control.LinearGauge
        PositionGauge_3                 matlab.ui.control.LinearGauge
        PositionGauge_2                 matlab.ui.control.LinearGauge
        SinusoidalSignalGeneratorPanel  matlab.ui.container.Panel
        AmplitudeEditField5             matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        AmplitudeEditField_21           matlab.ui.control.NumericEditField
        AmplitudeEditField_20           matlab.ui.control.NumericEditField
        AmplitudeEditField_19           matlab.ui.control.NumericEditField
        AmplitudeEditField_18           matlab.ui.control.NumericEditField
        AmplitudeEditField_16           matlab.ui.control.NumericEditField
        AmplitudeEditField_15           matlab.ui.control.NumericEditField
        AmplitudeEditField_14           matlab.ui.control.NumericEditField
        AmplitudeEditField_10           matlab.ui.control.NumericEditField
        AmplitudeEditField_13           matlab.ui.control.NumericEditField
        AmplitudeEditField_11           matlab.ui.control.NumericEditField
        AmplitudeEditField_7            matlab.ui.control.NumericEditField
        AmplitudeEditField_6            matlab.ui.control.NumericEditField
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        AmplitudeEditField_25           matlab.ui.control.NumericEditField
        AmplitudeEditField_24           matlab.ui.control.NumericEditField
        AmplitudeEditField_23           matlab.ui.control.NumericEditField
        AmplitudeEditField_22           matlab.ui.control.NumericEditField
        AmplitudeEditField_17           matlab.ui.control.NumericEditField
        AmplitudeEditField_12           matlab.ui.control.NumericEditField
        AmplitudeEditField_8            matlab.ui.control.NumericEditField
        AmplitudeEditField_9            matlab.ui.control.NumericEditField
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        ModelName = 'MotionLab' % Your Simulink model name
        SignalBlock = 'MotionLab/Big Stewart Platform System/Motion Control System/MotionGen' % Path to your signal generator block
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ApplyButtonPushed(app, event)
            
        end

        % Button pushed function: ApplyButton
        function ApplySurgeButtonPushed(app, event)
            % Define parameter names and corresponding edit fields
    parameterNames = {'Amplitude', 'Frequency', 'Phase', 'Offset'};
    directions = {'Surge', 'Sway', 'Heave', 'Roll', 'Pitch', 'Yaw'};
    editFields = {
        {app.AmplitudeEditField5, app.FrequencyEditField, app.PhaseEditField, app.OffsetEditField};
        {app.AmplitudeEditField_6, app.AmplitudeEditField_11, app.AmplitudeEditField_16, app.AmplitudeEditField_21};
        {app.AmplitudeEditField_7, app.AmplitudeEditField_12, app.AmplitudeEditField_17, app.AmplitudeEditField_22};
        {app.AmplitudeEditField_8, app.AmplitudeEditField_13, app.AmplitudeEditField_18, app.AmplitudeEditField_23};
        {app.AmplitudeEditField_9, app.AmplitudeEditField_14, app.AmplitudeEditField_19, app.AmplitudeEditField_24};
        {app.AmplitudeEditField_10, app.AmplitudeEditField_15, app.AmplitudeEditField_20, app.AmplitudeEditField_25}
    };

    % Loop through each direction and parameter to update variables in the base workspace
    for i = 1:length(directions)
        for j = 1:length(parameterNames)
            variableName = strcat(parameterNames{j}, directions{i});
            variableValue = editFields{i}{j}.Value;
            assignin('base', variableName, variableValue);
            % Debugging: Display the updated values
            disp(['Assigned ', variableName, ' = ', num2str(variableValue)]);
        end
    end
            % Optionally, run the simulation
            % sim(app.ModelName);
            %xATest = app.AmplitudeEditField5.Value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SinusoidalSignalGeneratorPanel = uipanel(app.UIFigure);
            app.SinusoidalSignalGeneratorPanel.Title = 'Sinusoidal Signal Generator';
            app.FrequencyEditFieldLabel = uilabel(app.SinusoidalSignalGeneratorPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_9 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_8 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_12 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_17 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_22 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_23 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_24 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_25 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.ApplyButton = uibutton(app.SinusoidalSignalGeneratorPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySurgeButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.OffsetEditFieldLabel = uilabel(app.SinusoidalSignalGeneratorPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_6 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_7 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_11 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_13 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_10 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_14 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_15 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_16 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_18 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_19 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_20 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField_21 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.SinusoidalSignalGeneratorPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.SinusoidalSignalGeneratorPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField5 = uieditfield(app.SinusoidalSignalGeneratorPanel, 'numeric');
            app.AmplitudeEditField5.Tag = 'AmplitudeSurgeField';
            app.ActuatorsPositionPanel = uipanel(app.UIFigure);
            app.ActuatorsPositionPanel.Title = 'Actuators Position';
            app.PositionGauge_2 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_2.Limits = [0 2];
            app.PositionGauge_2.Tag = 'ActPos';
            app.PositionGauge_3 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_3.Limits = [0 2];
            app.PositionGauge_3.Tag = 'ActPos';
            app.PositionGauge_4 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_4.Limits = [0 2];
            app.PositionGauge_4.Tag = 'ActPos';
            app.PositionGauge_5 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_5.Limits = [0 2];
            app.PositionGauge_5.Tag = 'ActPos';
            app.PositionGauge_6 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_6.Limits = [0 2];
            app.PositionGauge_6.Tag = 'ActPos';
            app.PositionGauge_7 = uigauge(app.ActuatorsPositionPanel, 'linear');
            app.PositionGauge_7.Limits = [0 2];
            app.PositionGauge_7.Tag = 'ActPos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:1'], app.PositionGauge_2, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:2'], app.PositionGauge_3, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:3'], app.PositionGauge_4, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:4'], app.PositionGauge_5, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:5'], app.PositionGauge_6, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ExtractAndCombinePosAndVel/Demux6:6'], app.PositionGauge_7, 'Value');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionLabHMI

            % Associate the Simulink Model
            app.Simulation = simulation('MotionLab');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end